classdef CNMFe_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UITable         matlab.ui.control.Table
        LoadDataButton  matlab.ui.control.Button
        DoneButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        theFiles % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
             app.theFiles = uipickfiles('FilterSpec','*.h5');
            
            File=app.theFiles';
            for i=1:size(File,1)
                PNR{i,1}=7;
                Coor{i,1}=0.8;
                sf{i,1}=10;
                gSig{i,1}=4;
                Get{i,1}='Press me';
            end     
            t=table(File,PNR,Coor,gSig,sf,Get);
            app.UITable.Data = t;
            
            s = uistyle('BackgroundColor','green');
            addStyle(app.UITable,s,'cell',[(1:size(File,1))',ones(size(File,1),1)*6]);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            indices = event.Indices;
            if indices(2)==6
                file=app.UITable.Data{indices(1),1};
                file=file{1, 1};
                Gsig=cell2mat(app.UITable.Data{indices(1),4});
                estimate_PNR_Coor_Thr(Gsig,file)
            end
            
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            assignin('base','parin',app.UITable.Data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File'; 'PNR'; 'Coor'; 'gSig'; 'sf'; 'Get'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNMFe_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end