classdef Label_spatial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        FinishButton   matlab.ui.control.Button
        ReorderButton  matlab.ui.control.Button
        PressstoselectcomponentsPressentertofinishselectionLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ix
        Img
        GridSize
        I
        A_com
    end
    
    methods (Access = private)
        
        function update_figure(app,row,col,gored)
            temp=max(app.Img(row:row+49,col:col+49,:),[],3);
            app.Img(row:row+49,col:col+49,:)=0;
            if gored
                app.Img(row:row+49,col:col+49,1)=temp;
            else
                app.Img(row:row+49,col:col+49,2)=temp;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, A)
            app.ix=zeros(size(A,3),1);
             app.A_com=A;
            for i=1:size( app.A_com,3)
                temp=squeeze( app.A_com(:,:,i));
                T(:,i)=double((temp(:)./max(temp(:))));
            end                    
            D=squareform(pdist(T','cosine'));         
            mi=median(D,1);
            [~,app.I]=sort(mi);
             app. A_com=  app.A_com(:,:,app.I); 
             
            app.GridSize=[ceil(sqrt(size(app.A_com,3)/2)),ceil(sqrt(size(app.A_com,3)/2))*2];
            app.Img = mat2gray(imtile(app.A_com,'GridSize',app.GridSize));
            app.Img = cat(3, zeros(size(app.Img,1),size(app.Img,2)),app.Img, zeros(size(app.Img,1),size(app.Img,2)));
            pause(1)
            imshow(app.Img,'Parent',app.UIAxes)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 's'
                    app.UIFigure.HandleVisibility = 'callback';
                    [x,y]=ginput_white;
                    app.UIFigure.HandleVisibility = 'off';
                    %                     roiPOC = drawpoint(app.UIAxes);    %Use Mouse To Select a point ROI
                    %                     PosPOC=round(get(roiPOC,'Position'));
                    %                     delete(roiPOC);    %Delete the point ROI Marker
                    %% Get neuron index
                    
                    for i=1:size(x,1)
             
                        id=x(i);
                        col=1:50:50*app.GridSize(2);
                        C=find(col<=id,1,'last');
                        col=col(C);
                        
                        id=y(i);
                        row=1:50:50*app.GridSize(1);
                        R=find(row<=id,1,'last');
                        row=row(R);
                        try
                            ind = sub2ind([app.GridSize(2),app.GridSize(1)],C,R);
                            if ind<=length(app.ix)
                                if app.ix(ind)==0
                                    app.ix(ind)=1;
                                    update_figure(app,row,col,1)
                                else
                                    app.ix(ind)=0;
                                    update_figure(app,row,col,0)
                                end
                            end
                        catch
                            
                        end
                        
                    end
                  imshow(app.Img,'Parent',app.UIAxes);
                  pause(2)
            end
        end

        % Button pushed function: ReorderButton
        function ReorderButtonPushed(app, event)
            
            [app.ix,temp_I]=sort(app.ix);
            app.A_com=app.A_com(:,:,temp_I);
            app.I=app.I(temp_I);
           
           A_temp=app.A_com(:,:,app.ix==0);
            for i=1:size( A_temp,3)
                temp=squeeze( A_temp(:,:,i));
                T(:,i)=double((temp(:)./max(temp(:))));
            end
            D=squareform(pdist(T','cosine'));
            mi=median(D,1);
            [~,temp_I2]=sort(mi);
            temp2=app.I(app.ix==0);
            
            app.I(app.ix==0)=temp2(temp_I2);
            app.A_com(:,:,app.ix==0)=A_temp(:,:,temp_I2);
            
            A1=app.A_com(:,:,app.ix==0);
            A1=cat(4, zeros(50,50,size(A1,3)),A1,zeros(50,50,size(A1,3)));
            A1=permute(A1,[1,2,4,3]);
            
            A2=app.A_com(:,:,app.ix==1);
            A2=cat(4,A2, zeros(50,50,size(A2,3)),zeros(50,50,size(A2,3)));
            A2=permute(A2,[1,2,4,3]);

            app.Img = mat2gray(imtile(cat(4,A1,A2),'GridSize',app.GridSize));
        
            imshow(app.Img,'Parent',app.UIAxes);
            pause(2)


        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            assignin('base','FP',app.I(logical(app.ix)));
            close(app. UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.PressstoselectcomponentsPressentertofinishselectionLabel = uilabel(app.UIFigure);
            app.PressstoselectcomponentsPressentertofinishselectionLabel.Text = 'Press ''s'' to select components. Press enter to finish selection';
            app.ReorderButton = uibutton(app.UIFigure, 'push');
            app.ReorderButton.ButtonPushedFcn = createCallbackFcn(app, @ReorderButtonPushed, true);
            app.ReorderButton.Text = 'Reorder';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Label_spatial(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end