classdef Mask_video_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        MaskMeButton   matlab.ui.control.Button
        DrawROIButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ROI
        out
        Cn
        Cn_all
        PNR
        PNR_all
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, m, out)
            app.Cn=m.Cn;
            app.Cn_all=m.Cn_all;
            app.PNR=m.PNR;
            app.PNR_all=m.PNR_all;
            imagesc(app.Cn,'parent',app.UIAxes)
            app.out=out;
        end

        % Button pushed function: MaskMeButton
        function MaskMeButtonPushed(app, event)
            Mask=app.ROI;
            out=app.out;  
            save(out,'Mask');
            closereq
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            temp = drawpolygon(app.UIAxes);
            app.ROI = createMask(temp);
            if (sum(app.ROI,'all')>0)
            app.MaskMeButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaskMeButton = uibutton(app.UIFigure, 'push');
            app.MaskMeButton.ButtonPushedFcn = createCallbackFcn(app, @MaskMeButtonPushed, true);
            app.MaskMeButton.Enable = 'off';
            app.MaskMeButton.Text = 'MaskMe';
            app.DrawROIButton = uibutton(app.UIFigure, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'DrawROI';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mask_video_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end