classdef Trim_video_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        LoadDataButton     matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        DoneButton         matlab.ui.control.Button
        RoundframesButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        V
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            theFiles = uipickfiles('FilterSpec','*.h5');
            File=theFiles';
            for k=1:length(theFiles)
                fullFileName = theFiles{k};
                app.TextArea.Value=[app.TextArea.Value;string(sprintf('Now reading %s\n....\n', fullFileName))];scroll(app.TextArea, 'bottom');
                app.V{k,1}=h5read(fullFileName,'/Object');
                frame_first{k,1}=1;
                frame_last{k,1}=size(app.V{k},3);
                t=table(File(1:k),frame_first,frame_last);
                app.UITable.Data = t;
                drawnow;
            end
            app.TextArea.Value=[app.TextArea.Value;string(sprintf('Done loading!\n\n'))];scroll(app.TextArea, 'bottom');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.UITable.ColumnEditable = false;
            app.LoadDataButton.Enable = 'off';
            app.DoneButton.Enable = 'off';
            app.RoundframesButton.Enable = 'off';
            
            A=table2array(app.UITable.Data);
            for k=1:size(app.V,1)
                f=string(table2cell(app.UITable.Data(k,1)));
                app.TextArea.Value=[app.TextArea.Value;string(sprintf('Now triming %s\n....\n', f))];scroll(app.TextArea, 'bottom');
                 drawnow;
                Vid=app.V{k};
                Vid=Vid(:,:,A{k,2}:A{k,3});
                [filepath,name]=fileparts(A{k,1});
                out=strcat(filepath,'\',name,'_trim','.h5');
                saveash5(Vid,out);           
            end
            app.delete;
        end

        % Button pushed function: RoundframesButton
        function RoundframesButtonPushed(app, event)
            T=cell2mat(table2cell(app.UITable.Data(:,3)));
            T=floor(T/100).*100;         
            app.UITable.Data(:,3)=table(num2cell(T));           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File'; 'First frame'; 'Last frame'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TextArea = uitextarea(app.UIFigure);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.RoundframesButton = uibutton(app.UIFigure, 'push');
            app.RoundframesButton.ButtonPushedFcn = createCallbackFcn(app, @RoundframesButtonPushed, true);
            app.RoundframesButton.Text = 'Round frames';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trim_video_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end