classdef view_traces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.Button
        SButton            matlab.ui.control.StateButton
        NeuronSlider       matlab.ui.control.Slider
        NeuronSliderLabel  matlab.ui.control.Label
        ZoomSlider         matlab.ui.control.Slider
        CButton            matlab.ui.control.StateButton
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        C
        S
        traces
        nTotal
        nStart
        yScale
        WinSize
        WinStart
        ofs
    end
    
    methods (Access = private)
        
        function plot_traces(app)
            
            if app.nTotal<1
                app.nTotal=1;
            end
            
            if app.nTotal>size(app.traces,2)
                app.nTotal=size(app.traces,2);
            end
            
            if app.nStart+app.nTotal-1>size(app.traces,2)
                app.nStart=size(app.traces,2)-app.nTotal+1;
            end
            if app.nStart<1
                app.nStart=1;
            end
            
            
             if app.WinSize<100
                app.WinSize=100;
            end
            
            if app.WinSize+1>size(app.traces,1)
                app.WinSize=size(app.traces,1)-1;
            end  
            
            
            if app.WinStart+app.WinSize-1>size(app.traces,1)
                app.WinStart=size(app.traces,1)-app.WinSize+1;
            end
            
            if app.WinStart<1
                app.WinStart=1;
            end
            if app.yScale<=0.1
                app.yScale=0.1;
            end
            if app.yScale>10
                app.yScale=10;
            end


            sca=(0:app.nTotal-1).*app.ofs;
            temp=app.traces(round(app.WinStart):round(app.WinStart+app.WinSize-1),round(app.nStart):round(app.nStart+app.nTotal-1)).*app.yScale+sca;
            plot(app.UIAxes,(app.WinStart:app.WinStart+app.WinSize-1),temp,'k');ylim(app.UIAxes,[0-app.ofs*0.2 1*app.nTotal*(app.ofs-1)])
            
            if app.CButton.Value==1
                hold(app.UIAxes,"on");
                temp_C=app.C(round(app.WinStart):round(app.WinStart+app.WinSize-1),round(app.nStart):round(app.nStart+app.nTotal-1)).*app.yScale+sca;
                plot(app.UIAxes,(app.WinStart:app.WinStart+app.WinSize-1),temp_C,'r');
                hold(app.UIAxes,"off");
            end
            
            if app.SButton.Value==1
                hold(app.UIAxes,"on");
                temp_S=full(app.S(round(app.WinStart):round(app.WinStart+app.WinSize-1),round(app.nStart):round(app.nStart+app.nTotal-1)).*app.yScale+sca);
                %                 temp_S(temp_S==0)=nan;
                %                 temp_S=temp_S+sca;
                %                 plot(app.UIAxes,(app.WinStart:app.WinStart+app.WinSize-1),temp_S,'mv','MarkerSize',5,'MarkerFaceColor','m');
                plot(app.UIAxes,(app.WinStart:app.WinStart+app.WinSize-1),temp_S,'m');
                hold(app.UIAxes,"off");
            end
            xlim(app.UIAxes,[app.WinStart app.WinStart+app.WinSize-1])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuron, inp)
            if ~exist('inp','var')
                inp = ones(size(neuron.C,1),1);
            end
            
            app.S=neuron.S(logical(inp),:)';
            app.C=neuron.C(logical(inp),:)';
            app.traces=neuron.C_raw(logical(inp),:)';
            %
            if (size(app.traces,2)<10)
              app.nTotal=size(app.C,2); 
            else
               app.nTotal=10;
            end
           
            app.nStart=1;
            
            app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
            app.NeuronSlider.Value=app.nStart;
            %
            app.yScale=1;
            %
            if (size(app.traces,1)<1000)
                app.WinSize=size(app.traces,1);
            else
                app.WinSize=1000;
            end
            
            app.WinSize=1000;
            app.WinStart=1;
            app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
            app.ZoomSlider.Value=app.WinStart;
            %
            
            temp=app.C;
            temp(temp<0.1)=nan;
            app.ofs=max(prctile(temp,90,2));
            
            disableDefaultInteractivity(app.UIAxes)
            plot_traces(app)
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            plot_traces(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                %%===========================
                case 'rightarrow'
                    if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                        app.WinStart=app.WinStart+app.WinSize;
                        plot_traces(app);
                        app.ZoomSlider.Value=app.WinStart;
                        app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
                    elseif ~isempty(event.Modifier) && strcmp(event.Modifier{1},'control')
                        app.WinSize=round(app.WinSize-app.WinSize*0.1);
                        plot_traces(app);
                        app.ZoomSlider.Value=app.WinStart;
                        app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
                    else
                        app.WinStart=app.WinStart+ceil(app.WinSize/10);
                        plot_traces(app);
                        app.ZoomSlider.Value=app.WinStart;
                        app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
                    end
                    %%===========================
                case 'leftarrow'
                    if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                        app.WinStart=app.WinStart-app.WinSize;
                        plot_traces(app);
                        app.ZoomSlider.Value=app.WinStart;
                        app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
                    elseif ~isempty(event.Modifier) && strcmp(event.Modifier{1},'control')
                        app.WinSize=round(app.WinSize+app.WinSize*0.1);
                        plot_traces(app);
                        app.ZoomSlider.Value=app.WinStart;
                        app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
                    else
                        app.WinStart=app.WinStart-ceil(app.WinSize/10);
                        plot_traces(app);
                        app.ZoomSlider.Value=app.WinStart;
                        app.ZoomSlider.Limits=[1 size(app.C,1)-app.WinSize+2];
                    end
                    %%===========================
                case 'uparrow'
                    if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                        app.nStart=app.nStart+app.nTotal;
                        plot_traces(app);
                        app.NeuronSlider.Value=app.nStart;
                        app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
                        
                    elseif ~isempty(event.Modifier) && strcmp(event.Modifier{1},'control')
                        app.nTotal=app.nTotal+1;
                        plot_traces(app);
                        app.NeuronSlider.Value=app.nStart;
                        app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
                        
                    else
                        app.nStart=app.nStart+ceil(app.nTotal/10);
                        plot_traces(app);
                        app.NeuronSlider.Value=app.nStart;
                        app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
                    end
                    %%===========================
                case 'downarrow'
                    if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                        app.nStart=app.nStart-app.nTotal;
                        plot_traces(app);
                        app.NeuronSlider.Value=app.nStart;
                        app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
                    elseif ~isempty(event.Modifier) && strcmp(event.Modifier{1},'control')
                        app.nTotal=app.nTotal-1;
                        plot_traces(app);
                        app.NeuronSlider.Value=app.nStart;
                        app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
                        
                    else
                        app.nStart=app.nStart-ceil(app.nTotal/10);
                        plot_traces(app);
                        app.NeuronSlider.Value=app.nStart;
                        app.NeuronSlider.Limits=[1 size(app.C,2)-app.nTotal+2];
                    end
            end
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            
            if (app.WinSize ~= event.Value)
                app.WinStart = event.Value;
                plot_traces(app);
            end
        end

        % Value changing function: NeuronSlider
        function NeuronSliderValueChanging(app, event)
            if (app.nStart ~= event.Value)
                app.nStart = event.Value;
                plot_traces(app);
            end
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            VerticalScrollCount = event.VerticalScrollCount;
            if (VerticalScrollCount~=0)
                app.yScale=app.yScale-VerticalScrollCount*0.1;
                plot_traces(app);
            end
            
        end

        % Value changed function: SButton
        function SButtonValueChanged(app, event)
            plot_traces(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            msgbox({'Commands'; '================';'Arrows...............     Scroll';'Shift+Arrow.......   Scroll X10';...
                ' Ctrl+Up/Down........   #Neurons to show';...
                'Ctrl+Left/Right........   Temporal Zoom'; 'Scroll Wheel....... Vertical Zoom'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Frame #')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [];
            app.CButton = uibutton(app.UIFigure, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.MinorTicks = [];
            app.NeuronSliderLabel = uilabel(app.UIFigure);
            app.NeuronSliderLabel.Text = '# Neuron';
            app.NeuronSlider = uislider(app.UIFigure);
            app.NeuronSlider.MajorTicks = [];
            app.NeuronSlider.ValueChangingFcn = createCallbackFcn(app, @NeuronSliderValueChanging, true);
            app.NeuronSlider.MinorTicks = [];
            app.SButton = uibutton(app.UIFigure, 'state');
            app.SButton.ValueChangedFcn = createCallbackFcn(app, @SButtonValueChanged, true);
            app.SButton.Text = 'S';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_traces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end